classdef arduinoAMC < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        GridLayout     matlab.ui.container.GridLayout
        LeftPanel      matlab.ui.container.Panel
        OnOFFButton    matlab.ui.control.StateButton
        InitAPPButton  matlab.ui.control.Button
        RightPanel     matlab.ui.container.Panel
        LOGLabel       matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        baseUrl = 'http://localhost:5000';
        toggleStat=0;
        fetchTimer 

    end
    
    methods (Access = public)
        
% Timer callback: Fetch the toggle parameter from the backend every 1 sec.
function autoFetchToggle(app)
    try
        % Get all parameters from the backend.
        params = webread([app.baseUrl '/parameters']);
        if isfield(params, 'toggle')
            newToggleVal = params.toggle.current;
            % Convert numeric value to logical
            if newToggleVal == 1
                newToggleBool = true;
            elseif newToggleVal == 0
                newToggleBool = false;
            else
                error('Unexpected toggle value: must be 0 or 1.');
            end
            % Update UI only if there's a change.
            if app.toggleStat ~= newToggleBool
                app.toggleStat = newToggleBool;
                app.OnOFFButton.Value = newToggleBool;
                app.LOGLabel.Text = sprintf('Auto-update: Toggle set to %d', newToggleBool);
            end
        else

        
            app.LOGLabel.Text = 'Toggle parameter not found in response.';
        end
    catch ME
        % Log errors without stopping the timer.
        app.LOGLabel.Text = sprintf('Error fetching parameter: %s', ME.message);
    end
end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: InitAPPButton
        function configStartup(app, event)
    try 
        toggleParam=struct('name','toggle','min',0,'max',1,'current',app.toggleStat);
        addResponse=webwrite([app.baseUrl '/parameters'],toggleParam,weboptions('MediaType','application/json','RequestMethod','post'));
        app.LOGLabel.Text = sprintf('Added parameter:\nCommand: %s\nStatus: %s', ...
            addResponse.command, addResponse.status);

    catch ME
        app.LOGLabel.Text = ['Error adding parameter: ' ME.message];

    end
     try
        softwareData = struct('software_name', 'MATLAB_GUI');
        softwareResponse = webwrite([app.baseUrl '/software'], softwareData);
        % Update LOGLabel with both command and status from the API response.
        app.LOGLabel.Text = sprintf('Command: %s\nStatus: %s', ...
            softwareResponse.command, softwareResponse.status);
    catch ME
        % Display the error message in the LOGLabel if the API call fails.
        app.LOGLabel.Text = ['Error: ' ME.message];
    end
    if isempty(app.fetchTimer) || ~isvalid(app.fetchTimer)
        app.fetchTimer = timer(...
            'ExecutionMode', 'fixedRate', ...
            'Period', 1, ...
            'TimerFcn', @(~,~) app.autoFetchToggle());
        start(app.fetchTimer);
        app.LOGLabel.Text = [app.LOGLabel.Text newline 'Auto-fetch timer started.'];
    else
        app.LOGLabel.Text = [app.LOGLabel.Text newline 'Auto-fetch timer already running.'];
    end

        end

        % Value changed function: OnOFFButton
        function ToggleBtnState(app, event)
            value = app.OnOFFButton.Value;
            
            app.toggleStat = value;  % Update internal state
            
            % Update the toggle state on the backend
            try
                % Construct the payload with the toggle state
                toggleData = struct('new_value', value);
                % Assume endpoint '/toggle' is used for updating toggle state.
                toggleResponse = webwrite([app.baseUrl '/parameter/toggle'], toggleData, ...
                    weboptions('MediaType','application/json','RequestMethod','put'));
                % Optionally, update LOGLabel with the response status.
                app.LOGLabel.Text = sprintf('Toggle update:\n%s', toggleResponse.status);
            catch ME
                app.LOGLabel.Text = ['Error updating toggle state: ' ME.message];
            end
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.InitAPPButton = uibutton(app.LeftPanel, 'push');
            app.InitAPPButton.ButtonPushedFcn = createCallbackFcn(app, @configStartup, true);
            app.InitAPPButton.Text = 'Init APP';
            app.OnOFFButton = uibutton(app.LeftPanel, 'state');
            app.OnOFFButton.ValueChangedFcn = createCallbackFcn(app, @ToggleBtnState, true);
            app.OnOFFButton.Text = 'On/OFF';
            app.RightPanel = uipanel(app.GridLayout);
            app.LOGLabel = uilabel(app.RightPanel);
            app.LOGLabel.Text = 'LOG';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = arduinoAMC

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end